classdef MarmosetCerebellumFlatmapTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        ButtonGroup                 matlab.ui.container.ButtonGroup
        Button_Label                matlab.ui.control.RadioButton
        Button_Border               matlab.ui.control.RadioButton
        Button_Curvature            matlab.ui.control.RadioButton
        Button_Intensity            matlab.ui.control.RadioButton
        CheckBox_PointCloud         matlab.ui.control.CheckBox
        Label_RegionName            matlab.ui.control.Label
        Button_LoadIntensity        matlab.ui.control.Button
        EditField_ThresholdNeg      matlab.ui.control.NumericEditField
        EditField_ThresholdPos      matlab.ui.control.NumericEditField
        Slider_IntensityRange       matlab.ui.control.RangeSlider
        Button_ResetIntensityRange  matlab.ui.control.Button
        Button_LoadPointCloud       matlab.ui.control.Button
        ColorPicker_PointCloud      matlab.ui.control.ColorPicker
        Hyperlink_SamplePointCloud  matlab.ui.control.Hyperlink
        UIAxes                      matlab.ui.control.UIAxes
    end

    % This app leverages the CerebellumFlatmapGenerator to visualize a
    % variety of flat maps derived from 3D marmoset cerebellum label data,
    % and enables overlaying volumetric intensity information and mapping
    % of cerebellar point-cloud coordinates onto those flat maps.

    % HISTORY:
    %   1.0 - 20250508 Written by Mitsu
    %   1.1 - 20250514 a) Implemented core functionality and refined the
    %                     GUI to reach a basic working state.
    %                  b) Fix data folder path for deployment.
    %   1.2 - 20250516 a) Moved code for generating various flatmaps from
    %                     marmoset cerebellum label data into
    %                     CerebellumFlatmapGenerator.
    %                  b) Now loads pre-saved CerebellumFlatmapGenerator
    %                     handle on launch, avoiding repeated flatmap
    %                     creation.
    %   1.3 - 20250527 Modified the figure X-axis for world-space
    %                  coordinate display and refined flatmap visualization.
    %   1.4 - 20250617 a) Added functionality to display the label at the
    %                     current cursor position on the flatmap.
    %                  b) Fixed a bug that allowed zooming and panning
    %                     outside the axes area.
    %                  c) Fixed a bug that attempted to get label indices
    %                     from the image at the axes boundary.
    %   1.5 - 20250702 a) Make several appearance adjustments.
    %                  b) Allow user input for intensity threshold.

    properties (Access = private)

        % App information.
        pAppName = "Marmoset Cerebellum Flatmap Tool";
        pAppVersion = 1.5;

        % Utility object handles.
        hAppUtilities
        hFigUtilities

        % A CerebellumFlatmapGenerator handle.
        hCerebellumFlatmapGenerator

        % Created intensity flatmap and its colormap data.
        pFlatmapIntensity
        pColormapIntensity

        % Data folder and contained file names.
        pDataFolderName = "data";
        pDataFileName = "data.mat";
        pSamplePointCloudName = "sample_point_cloud.csv";

        % Field names of the struct stored in the data file.
        pFieldNameGenerator = "CerebellumFlatmapGenerator";
        
        % Flatmap type names.
        pFlatmapTypeNameLabel     = CerebellumFlatmapGenerator.cTypeNameLabel;
        pFlatmapTypeNameBorder    = CerebellumFlatmapGenerator.cTypeNameBorder;
        pFlatmapTypeNameCurvature = CerebellumFlatmapGenerator.cTypeNameCurvature;
        pFlatmapTypeNameIntensity = CerebellumFlatmapGenerator.cTypeNameIntensity;       

        % Minimum and maximum limits of colormaps.
        pColormapLimMinIntensity = CerebellumFlatmapGenerator.cIntensityMin;
        pColormapLimMaxIntensity = CerebellumFlatmapGenerator.cIntensityMax;
        pColormapLimMinCurvature = -10;
        pColormapLimMaxCurvature =  10;

        % A scaling ratio for stretching the flatmap in the X direction.
        pAspectRatioX = 250;        

        % Valid file extensions for 3D intensity data (NIfTI files).
        pValidFileExtensionsIntensity = [ ...
            ".gz", ...
            ".nii", ...
            ".hdr", ...
            ".img" ...
        ];

        % Valid file extensions for point cloud data.
        pValidFileExtensionsPointCloud = ".csv";

        % Plot size and color of the mapped point cloud
        pMappedPointSize  = 20;
        pMappedPointColorInit = "red";

        % Row and column indices on the flatmap corresponding to the
        % currently loaded point cloud.
        pMappedPointRows
        pMappedPointColumns

        % Scatter handle for the mapped point cloud.
        hScatterPointCloud

        % Default labels for components.
        pDefaultLabelButtonIntensity    = "Intensity";
        pDefaultLabelCheckBoxPointCloud = "Point Cloud";

        % Component tag names.
        pComponentTagNeg = "neg";
        pComponentTagPos = "pos";

    end
    
    methods (Access = private)

        % Initialize the app.

        function init(app)

            % Create a FigUtilities. (FigUtilities, 1 x 1)
            app.hFigUtilities = FigUtilities(app.UIFigure,app.UIAxes);

            % Set tags for the components.
            app.EditField_ThresholdNeg.Tag = app.pComponentTagNeg;
            app.EditField_ThresholdPos.Tag = app.pComponentTagPos;

            % Initialize appearance.
            app.initAppearance();

            % Load and store a CerebellumFlatmapGenerator handle.
            app.storeCerebellumFlatmapGenerator();
            
            % Show the label flatmap.
            app.showFlatmap(app.pFlatmapTypeNameLabel);

            % Enable default zoom and pan functionality alongside a custom
            % WindowButtonMotionFcn callback.
            EnableCustomFigureZoomPan( ...
                app.UIFigure, ...
                app.UIAxes, ...
                @app.showCurrentRegionName ...
            );

            % NOTE:
            % Zoom and pan will be set according to the currently displayed
            % image, so display the flatmap before use.

        end

        function initAppearance(app)

            % Build an application name. (string, 1 x 1)
            appName = buildFormattedAppName( ...
                app.pAppName, ...
                app.pAppVersion ...
            );

            % Set the UI figure name.
            app.hAppUtilities.setFigureName(appName);

            % Assign tags to each toggle button.
            app.Button_Label.Tag     = app.pFlatmapTypeNameLabel;
            app.Button_Border.Tag    = app.pFlatmapTypeNameBorder;
            app.Button_Curvature.Tag = app.pFlatmapTypeNameCurvature;
            app.Button_Intensity.Tag = app.pFlatmapTypeNameIntensity;

            % Show a sample image.
            sampleImage = imread("peppers.png");
            imagesc(app.UIAxes,sampleImage);

            % Configure the appearance of the X and Y axes.
            axis(app.UIAxes,"image");
            app.UIAxes.Box = "on";
            app.UIAxes.Color = "none";
            app.UIAxes.XTickMode = "auto";
            app.UIAxes.XLabel.Units = "normalized";
            app.UIAxes.YTick = [];
            app.UIAxes.XLabel.Position = [0.5,-0.07,0];
            app.UIAxes.Layer = "top";
            app.UIAxes.GridLineStyle = ":";            

            % Set the x label.
            app.UIAxes.XLabel.String = ...
                "Sagittal Slice Position in World Space (mm; –: left, +: right)";

            % Initialize the slider and edit field.
            app.setIntensityLimits();
            app.resetIntensityThresholds();            

            % Set the default labels for components.
            app.Button_Intensity.Text    = app.pDefaultLabelButtonIntensity;
            app.CheckBox_PointCloud.Text = app.pDefaultLabelCheckBoxPointCloud;

            % Set default color in the color picker.
            app.ColorPicker_PointCloud.Value = app.pMappedPointColorInit;

            % Set the hyperlink text.
            app.Hyperlink_SamplePointCloud.Text = ...
                sprintf("Download %s",app.pSamplePointCloudName);

            drawnow;
            pause(0.3);

        end

        function setIntensityLimits(app)

            intensityLimits = [
                app.pColormapLimMinIntensity, ...
                app.pColormapLimMaxIntensity ...
            ];

            % Set the intensity limits for both the slider and the edit
            % field.
            app.Slider_IntensityRange.Limits  = intensityLimits;
            app.EditField_ThresholdNeg.Limits = intensityLimits;
            app.EditField_ThresholdPos.Limits = intensityLimits;

        end

        function resetIntensityThresholds(app)

            tholdNeg = CerebellumFlatmapGenerator.cIntensityNegTholdInit;
            tholdPos = CerebellumFlatmapGenerator.cIntensityPosTholdInit;

            % Reset the intensity threshold for both the slider and the
            % edit field.
            app.Slider_IntensityRange.Value = [tholdNeg,tholdPos];
            app.EditField_ThresholdNeg.Value = tholdNeg;
            app.EditField_ThresholdPos.Value = tholdPos;

        end

        function storeCerebellumFlatmapGenerator(app)

            % Show a progress dialog.
            app.hAppUtilities.showProgressDialog( ...
                sprintf("Loading CerebellumFlatmapGenerator handle...") ...
            );

            % Build the path to the data file. (string, 1 x 1) 
            filePath = fullfile(app.getDataFolderPath(),app.pDataFileName);

            % Load the file. (struct, 1 x 1)
            data = load(filePath);

            % Store the CerebellumFlatmapGenerator handle.
            app.hCerebellumFlatmapGenerator = data.(app.pFieldNameGenerator);

            % Delete the progress dialog.
            app.hAppUtilities.deleteProgressDialog();

        end

        function dataFolder = getDataFolderPath(app)

            % Get directory where the application exists.
            appDir = getAppDirectory(app);

            % Return the data folder path. (string, 1 x 1)
            dataFolder = fullfile(appDir,app.pDataFolderName);

        end       

        % Show flatmaps.

        function showFlatmap(app,flatmapType,varargin)

            colormapLimMin = [];
            colormapLimMax = [];
            setNaNTransparent = [];

            switch flatmapType

                case app.pFlatmapTypeNameLabel

                    flatmap  = app.hCerebellumFlatmapGenerator.pFlatmapLabel;
                    colormap = app.hCerebellumFlatmapGenerator.pColormapLabel;

                case app.pFlatmapTypeNameBorder

                    flatmap  = app.hCerebellumFlatmapGenerator.pFlatmapBorder;
                    colormap = app.hCerebellumFlatmapGenerator.pColormapBorder;

                case app.pFlatmapTypeNameCurvature

                    flatmap  = app.hCerebellumFlatmapGenerator.pFlatmapCurvature;
                    colormap = app.hCerebellumFlatmapGenerator.pColormapCurvature;
                    colormapLimMin = app.pColormapLimMinCurvature;
                    colormapLimMax = app.pColormapLimMaxCurvature;
                    setNaNTransparent = true;

                case app.pFlatmapTypeNameIntensity

                    if isempty(app.pFlatmapIntensity)

                        % Show a progress dialog.
                        app.hAppUtilities.showProgressDialog( ...
                            "Creating the intensity flatmap..." ...
                        );
                        
                        % Create and store the intensity flatmap.
                        app.pFlatmapIntensity = ...
                            app.hCerebellumFlatmapGenerator.createIntensityFlatmap( ...
                                varargin{:} ...
                            );

                        % Create and store the colormap.
                        app.storeCurrentIntensityColormap();

                        % Delete the progress dialog.
                        app.hAppUtilities.deleteProgressDialog();

                    end

                    flatmap  = app.pFlatmapIntensity;
                    colormap = app.pColormapIntensity;
                    colormapLimMin = app.pColormapLimMinIntensity;
                    colormapLimMax = app.pColormapLimMaxIntensity;
                    setNaNTransparent = false;

            end

            % Show the flatmap.
            app.updateFlatmapInUIAxes( ...
                flatmap, ...
                colormap, ...
                colormapLimMin, ...
                colormapLimMax, ...
                setNaNTransparent ...
            );           

        end

        function storeCurrentIntensityColormap(app)

            % Get the intensity range.
            range = app.Slider_IntensityRange.Value;

            % Recreate and sore the colormap. (double, 1 x 3)
            app.pColormapIntensity = ...
                app.hCerebellumFlatmapGenerator.createIntensityColormap( ...
                    range(1), ...
                    range(2) ...
                );

        end

        % Update the flatmap.

        function updateFlatmapInUIAxes(app, ...
                flatmap, ...
                colormap, ...
                colormapLimMin, ...
                colormapLimMax, ...
                setNaNTransparent ...
            )

            % Get world-space X sampling coordinates corresponding to each
            % column of the flatmap. (double, 1 x flatmapWidth)
            worldXCoordsPerColumn = ...
                app.hCerebellumFlatmapGenerator.pWorldXCoordsPerColumn;

            if istable(colormap)

                % Get the colors and label names.
                % (uint8, numColors x RGB), (string, numColors x 1), 
                labelColors = colormap.(CerebellumFlatmapGenerator.cVarNameColor);
                labelNames  = colormap.(CerebellumFlatmapGenerator.cVarNameLabelName);
    
                % Show the flatmap.
                imshow( ...
                    flatmap, ...
                    labelColors, ...
                    Parent = app.UIAxes, ...
                    XData = worldXCoordsPerColumn ...
                );

                % Show axis ticks again.
                app.UIAxes.Visible = "on";
    
                % Show the color bar.
                colorbar(app.UIAxes, ...
                    Direction = "reverse", ...
                    TickLabels = labelNames, ...
                    TickLabelInterpreter = "none", ...
                    Ticks = (0:numel(labelNames))+0.5 ...
                );

            else

                % Set NaN pixels to be transparent.
                if setNaNTransparent
                    alphaData = ~isnan(flatmap);
                else
                    alphaData = 1; % Default
                end

                % Show the flatmap.
                imagesc( ...
                    app.UIAxes, ...
                    flatmap, ...
                    XData = worldXCoordsPerColumn, ...
                    AlphaData = alphaData ... 
                );
               
                % Set the colormap.
                app.UIAxes.Colormap = colormap;
                app.UIAxes.CLim = [colormapLimMin,colormapLimMax];

                % Show the color bar.
                tickStep = round((colormapLimMax-colormapLimMin)/10);
                colorbar(app.UIAxes, ...
                    Ticks = colormapLimMin:tickStep:colormapLimMax ...
                );

            end            
            
            % Set the aspect ratio of the figure.
            app.UIAxes.DataAspectRatio = [1,app.pAspectRatioX,1];

            app.UIAxes.XGrid = "on";
            app.UIAxes.YGrid = "on";

            % Show the currently loaded point cloud onto the current
            % flatmap.
            app.showCurrentPointCloudOnFlatmap();

        end

        function showCurrentPointCloudOnFlatmap(app)

            if isempty(app.pMappedPointRows) || ...
               isempty(app.pMappedPointColumns) || ...
               ~app.PointCloudIsChecked()
               return; 
            end

            % Delete the current the point cloud.
            app.deleteCurrentPointCloud();

            % Get world-space X sampling coordinates corresponding to each
            % column of the flatmap. (double, 1 x flatmapWidth)
            worldXCoordsPerColumn = ...
                app.hCerebellumFlatmapGenerator.pWorldXCoordsPerColumn;

            % Show the currently loaded point cloud onto the current
            % flatmap.
            hold(app.UIAxes,"on");
            app.hScatterPointCloud = scatter( ...
                app.UIAxes, ...
                worldXCoordsPerColumn(app.pMappedPointColumns), ...
                app.pMappedPointRows, ...
                app.pMappedPointSize, ...
                "filled", ...
                "MarkerFaceColor",app.ColorPicker_PointCloud.Value ...
            );
            hold(app.UIAxes,"off");

        end

        function deleteCurrentPointCloud(app)

            % Delete the current the point cloud.
            delete(app.hScatterPointCloud);

        end

        % Load and map new intensity data.

        function loadAndMapNewIntensityData(app)

            % Ask user to specify a path to a file.
            [fileName,folder] = app.hAppUtilities.askUserToSpecifyFileToInput( ...
                "NIfTI image", ...
                validExts = app.pValidFileExtensionsIntensity ...
            );

            % If user canceled, stop asking again.
            if isempty(fileName)
                return;
            end

            % Build the file path. (string, 1 x 1)
            filePath = fullfile(folder,fileName);

            % Save the selected folder path.
            app.hAppUtilities.setLastSelectedFolder(folder);

            % Store the current intensity flatmap as the previous one.
            flatmapIntensityPrevious = app.pFlatmapIntensity;

            % Clear the current intensity flatmap.
            app.pFlatmapIntensity = [];

            try                
    
                % Show the intensity flatmap.
                app.showFlatmap(app.pFlatmapTypeNameIntensity,filePath);

            catch ME

                % Restore the flatmap.
                app.pFlatmapIntensity = flatmapIntensityPrevious;

                % Rethrow the exception.
                rethrow(ME);

            end

            % Enable the toggle button for displaying the intensity
            % flatmap.
            app.Button_Intensity.Enable = "on";

            % Select the button for the intensity flatmap.
            app.selectIntensityButton();

            % Update the button label with the filename and set a tooltip.
            app.setLabelWithFilenameAndTooltip( ...
                app.Button_Intensity, ...
                app.pDefaultLabelButtonIntensity, ...
                fileName ...
            );

        end

        function selectIntensityButton(app)

            % Select the button for the intensity flatmap.
            app.Button_Intensity.Value = true;

        end

        function setLabelWithFilenameAndTooltip(~,hComponent,defaultLabel,fileName)

            % Update the component label to include the file name and
            % assign the same string as the tooltip.
            hComponent.Text = sprintf("%s (%s)",defaultLabel,fileName);
            hComponent.Tooltip = fileName;

        end

        % Apply the updated intensity threshold.

        function resetAndApplyIntensityThresholds(app)

            % Reset the intensity threshold.
            app.resetIntensityThresholds();

            % Apply the updated intensity threshold.
            app.applyIntensityThreshold();

        end

        function syncAndApplyEditFieldChange(app,tag)

            % Update the slider to match the edit field value.
            app.syncSliderToEditField(tag);
            
            % Apply the updated intensity threshold.
            app.applyIntensityThreshold();

        end

        function applyIntensityThreshold(app)

            if isempty(app.pFlatmapIntensity)
                return;
            end

            % Create and store the current intensity colormap.
            app.storeCurrentIntensityColormap();

            % Re-show the intensity flatmap.
            app.showFlatmap(app.pFlatmapTypeNameIntensity);

            % Select the button for the intensity flatmap.
            app.selectIntensityButton();

        end

        % Synchronize the values of the edit field and the slider.

        function syncEditFieldsToSlider(app,sliderValue)

            % Update the edit field to match the slider value.
            app.EditField_ThresholdNeg.Value = sliderValue(1);
            app.EditField_ThresholdPos.Value = sliderValue(2);

        end

        function syncSliderToEditField(app,tag)

            % Get the updated thresholds.
            tholdNeg = app.EditField_ThresholdNeg.Value;
            tholdPos = app.EditField_ThresholdPos.Value;

            % If the negative field value becomes greater than the positive
            % field value, apply the updated value to both fields.
            if tholdNeg > tholdPos
            
                switch tag
                    case app.pComponentTagNeg
                        app.EditField_ThresholdPos.Value = tholdNeg;
                    case app.pComponentTagPos
                        app.EditField_ThresholdNeg.Value = tholdPos;
                end

            end

            % Update the slider to match the edit field value.
            app.Slider_IntensityRange.Value = [
                app.EditField_ThresholdNeg.Value, ...
                app.EditField_ThresholdPos.Value
            ];            

        end

        % Load and map new point cloud data.

        function loadAndMapNewPointCloudData(app)

            % Ask user to specify a path to a file.
            [fileName,folder] = app.hAppUtilities.askUserToSpecifyFileToInput( ...
                "coordinates", ...
                validExts = app.pValidFileExtensionsPointCloud ...
            );

            % If user canceled, stop asking again.
            if isempty(fileName)
                return;
            end

            % Show a progress dialog.
            app.hAppUtilities.showProgressDialog( ...
                "Mapping the point cloud onto the current flatmap..." ...
            );

            % Build the file path. (string, 1 x 1)
            filePath = fullfile(folder,fileName);

            % Save the selected folder path.
            app.hAppUtilities.setLastSelectedFolder(folder);

            % Compute the row and column indices on the flatmap
            % corresponding to the given point cloud in world space.
            % (double, numQueryPoints x 1)
            [mappedPointRows,mappedPointColumns] = ...
                app.hCerebellumFlatmapGenerator.mapWorldPointsToFlatmap( ...
                    filePath ...
                );

            % Remove NaN values and store in the property.
            app.pMappedPointRows    = rmmissing(mappedPointRows);
            app.pMappedPointColumns = rmmissing(mappedPointColumns);

            % Enable and select the check box for displaying the point
            % cloud.
            app.CheckBox_PointCloud.Enable = "on";
            app.CheckBox_PointCloud.Value  = true;

            % NOTE:
            % Check the box before showing the point cloud.

            % Show the point cloud onto the current flatmap.
            app.showCurrentPointCloudOnFlatmap();            

            % Delete the progress dialog.
            app.hAppUtilities.deleteProgressDialog();

            % Update the button label with the filename and set a tooltip.
            app.setLabelWithFilenameAndTooltip( ...
                app.CheckBox_PointCloud, ...
                app.pDefaultLabelCheckBoxPointCloud, ...
                fileName ...
            );

        end

        % Callback when the point cloud checkbox is updated.

        function pointCloudCheckBoxValueChanged(app)

            if app.PointCloudIsChecked()

                % Show the currently loaded point cloud onto the current
                % flatmap.
                app.showCurrentPointCloudOnFlatmap();

            else

                % Delete the current the point cloud.
                app.deleteCurrentPointCloud();

            end

        end

        function tf = PointCloudIsChecked(app)

            % Return whether the checkbox for the point cloud is checked.
            tf = app.CheckBox_PointCloud.Value;

        end

        % Download sample point cloud data.

        function downloadSamplePointCloudFile(app)

            % Ask user to specify an output file name.
            [fileName,folder] = app.hAppUtilities.askUserToSpecifyFileToOutput( ...
                "sample point cloud data", ...
                validExts = app.pValidFileExtensionsPointCloud, ...
                defaultFilePath = app.pSamplePointCloudName ...
            );

            if isempty(fileName)
                return;
            end

            % Build the output file path. (string, 1 x 1)
            outputFilePath = fullfile(folder,fileName);

            % Save the selected folder path.
            app.hAppUtilities.setLastSelectedFolder(folder);

            % Build the path to the sample point cloud data file.
            % (string, 1 x 1) 
            sampleFilePath = fullfile( ...
                app.getDataFolderPath(), ...
                app.pSamplePointCloudName ...
            );

            % Copy the sample file to the output path.
            [status,msg] = copyfile(sampleFilePath,outputFilePath);
                
            if status
                return;
            end
            
            % Show an alert message if it's failed.
            app.hAppUtilities.showAlertDialog( ...
                msg, ...
                pauseUntilAcknowledged = true ...
            );

        end

        % Show the region name at the current cursor position.

        function showCurrentRegionName(app)

            % Clear the region label and return if outside the axes area.
            if ~app.hFigUtilities.isCursorInAxes()
                app.clearRegionLabel();
                return;
            end

            cursorPosition = app.UIAxes.CurrentPoint;

            % Get cursor coordinates on flatmap.
            worldX = cursorPosition(1,1);
            row    = round(cursorPosition(1,2));

            % Get world-space X sampling coordinates corresponding to each
            % column of the flatmap. (double, 1 x flatmapWidth)
            worldXCoordsPerColumn = ...
                app.hCerebellumFlatmapGenerator.pWorldXCoordsPerColumn;

            % Get label flatmap.
            flatmap = app.hCerebellumFlatmapGenerator.pFlatmapLabel;

            % Clear the region label and return if outside the image range.
            if row < 1 || ...
               row > size(flatmap,1) || ...
               worldX < worldXCoordsPerColumn(1) || ...
               worldX > worldXCoordsPerColumn(end)

                app.clearRegionLabel();
                return;
            end            

            % Convert to column number.
            [~,column] = min(abs(worldXCoordsPerColumn-worldX));

            % Get the label ID of the pixel at the current cursor position
            % on the flatmap.
            labelId = flatmap(row,column);

            % Get the label names.
            colormap = app.hCerebellumFlatmapGenerator.pColormapLabel;
            labelNames = colormap.(CerebellumFlatmapGenerator.cVarNameLabelName);

            % Show the label name at the current cursor position.
            app.Label_RegionName.Text = sprintf("%s",labelNames(labelId+1));

        end

        function clearRegionLabel(app)

            % Clear the current region label.
            app.Label_RegionName.Text = "";

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Create an AppUtilities. (AppUtilities, 1 x 1)
            app.hAppUtilities = AppUtilities(app.UIFigure);

            % Initialize the app.
            app.hAppUtilities.runWithErrorHandling( ...
                @app.init ...
            );

        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            
            % Show the selected flatmap.
            app.hAppUtilities.runWithErrorHandling( ...
                @()app.showFlatmap(app.ButtonGroup.SelectedObject.Tag) ...
            );

        end

        % Button pushed function: Button_LoadIntensity
        function Button_LoadIntensityPushed(app, event)
            
            % Load and map new intensity data.
            app.hAppUtilities.runWithErrorHandling( ...
                @app.loadAndMapNewIntensityData ...
            );

        end

        % Value changing function: Slider_IntensityRange
        function Slider_IntensityRangeValueChanging(app, event)

            % Update the edit field to match the slider value.
            app.hAppUtilities.runWithErrorHandling( ...
                @()app.syncEditFieldsToSlider(event.Value) ...
            );
            
        end

        % Value changed function: Slider_IntensityRange
        function Slider_IntensityRangeValueChanged(app, event)

            % Apply the updated intensity threshold.
            app.hAppUtilities.runWithErrorHandling( ...
                @app.applyIntensityThreshold ...
            );
            
        end

        % Value changed function: EditField_ThresholdNeg, 
        % ...and 1 other component
        function EditField_ThresholdValueChanged(app, event)

            % Update the slider to match the edit field value and apply
            % them.
            app.hAppUtilities.runWithErrorHandling( ...
                @()app.syncAndApplyEditFieldChange(event.Source.Tag) ...
            );
            
        end

        % Button pushed function: Button_ResetIntensityRange
        function Button_ResetIntensityRangePushed(app, event)
            
            % Reset the intensity thresholds to default values and apply
            % them.
            app.hAppUtilities.runWithErrorHandling( ...
                @app.resetAndApplyIntensityThresholds ...
            );

        end

        % Button pushed function: Button_LoadPointCloud
        function Button_LoadPointCloudPushed(app, event)
            
            % Load and map new point cloud data.
            app.hAppUtilities.runWithErrorHandling( ...
                @app.loadAndMapNewPointCloudData ...
            );

        end

        % Value changed function: CheckBox_PointCloud
        function CheckBox_PointCloudValueChanged(app, event)
            
            % Run the callback when the point cloud checkbox is updated.
            app.hAppUtilities.runWithErrorHandling( ...
                @app.pointCloudCheckBoxValueChanged ...
            );
            
        end

        % Callback function: ColorPicker_PointCloud
        function ColorPicker_PointCloudValueChanged(app, event)
            
            % Show the currently loaded point cloud onto the current
            % flatmap with the updated color.
            app.hAppUtilities.runWithErrorHandling( ...
                @app.showCurrentPointCloudOnFlatmap ...
            );

        end

        % Callback function: Hyperlink_SamplePointCloud
        function Hyperlink_SamplePointCloudClicked(app, event)
            
            % Download sample point cloud data.
            app.hAppUtilities.runWithErrorHandling( ...
                @app.downloadSamplePointCloudFile ...
            );

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.Hyperlink_SamplePointCloud = uihyperlink(app.GridLayout);
            app.Hyperlink_SamplePointCloud.HyperlinkClickedFcn = createCallbackFcn(app, @Hyperlink_SamplePointCloudClicked, true);
            app.Hyperlink_SamplePointCloud.Text = '';
            app.ColorPicker_PointCloud = uicolorpicker(app.GridLayout);
            app.ColorPicker_PointCloud.ValueChangedFcn = createCallbackFcn(app, @ColorPicker_PointCloudValueChanged, true);
            app.Button_LoadPointCloud = uibutton(app.GridLayout, 'push');
            app.Button_LoadPointCloud.ButtonPushedFcn = createCallbackFcn(app, @Button_LoadPointCloudPushed, true);
            app.Button_LoadPointCloud.Text = 'Load Point Cloud';
            app.Button_ResetIntensityRange = uibutton(app.GridLayout, 'push');
            app.Button_ResetIntensityRange.ButtonPushedFcn = createCallbackFcn(app, @Button_ResetIntensityRangePushed, true);
            app.Button_ResetIntensityRange.Text = 'Reset';
            app.Slider_IntensityRange = uislider(app.GridLayout, 'range');
            app.Slider_IntensityRange.ValueChangedFcn = createCallbackFcn(app, @Slider_IntensityRangeValueChanged, true);
            app.Slider_IntensityRange.ValueChangingFcn = createCallbackFcn(app, @Slider_IntensityRangeValueChanging, true);
            app.EditField_ThresholdPos = uieditfield(app.GridLayout, 'numeric');
            app.EditField_ThresholdPos.ValueDisplayFormat = '%.2f';
            app.EditField_ThresholdPos.ValueChangedFcn = createCallbackFcn(app, @EditField_ThresholdValueChanged, true);
            app.EditField_ThresholdNeg = uieditfield(app.GridLayout, 'numeric');
            app.EditField_ThresholdNeg.ValueDisplayFormat = '%.2f';
            app.EditField_ThresholdNeg.ValueChangedFcn = createCallbackFcn(app, @EditField_ThresholdValueChanged, true);
            app.Button_LoadIntensity = uibutton(app.GridLayout, 'push');
            app.Button_LoadIntensity.ButtonPushedFcn = createCallbackFcn(app, @Button_LoadIntensityPushed, true);
            app.Button_LoadIntensity.Text = 'Load Intensity';
            app.Label_RegionName = uilabel(app.GridLayout);
            app.Label_RegionName.Text = '';
            app.CheckBox_PointCloud = uicheckbox(app.GridLayout);
            app.CheckBox_PointCloud.ValueChangedFcn = createCallbackFcn(app, @CheckBox_PointCloudValueChanged, true);
            app.CheckBox_PointCloud.Enable = 'off';
            app.CheckBox_PointCloud.Text = '';
            app.ButtonGroup = uibuttongroup(app.GridLayout);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.Button_Intensity = uiradiobutton(app.ButtonGroup);
            app.Button_Intensity.Enable = 'off';
            app.Button_Intensity.Text = '';
            app.Button_Curvature = uiradiobutton(app.ButtonGroup);
            app.Button_Curvature.Text = 'Curvature';
            app.Button_Border = uiradiobutton(app.ButtonGroup);
            app.Button_Border.Text = 'Border';
            app.Button_Label = uiradiobutton(app.ButtonGroup);
            app.Button_Label.Text = 'Label';
            app.Button_Label.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MarmosetCerebellumFlatmapTool

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end